classdef guiPrototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SynthlabPROTOTYPELabel  matlab.ui.control.Label
        PLAYButton              matlab.ui.control.Button
        FreqHzSliderLabel       matlab.ui.control.Label
        FreqHzSlider            matlab.ui.control.Slider
        btnPlay                 matlab.ui.control.StateButton
    end


    properties (Access = private)
        decFreq = 750; % frequency
    end

    methods (Static)
    
        function TestTone(app, freq)
            % Simple sine wave
            amp = 0.05;
%             freq = 750;
%             dur = 1;
            riseDur = 0;
            Fs = 48000;
            dur = 1;
            phase = 0;
            [tone, tVec] = app.genTone(amp, freq, dur, riseDur, phase, Fs);
        
            % clf
            % plot(tVec, tone)
            % ylabel('Amp, V')
            % xlabel('Time, S')
            % hold on
            % scatter(tVec, tone)
            
            % TODO: try the dsp toolbox methods for cts audio playback.
            p = audioplayer(tone, Fs, 24); %dsp.AudioPlayer('SampleRate',Fs);
%             audioplayer.set(
            while app.btnPlay.Value
                playblocking(p);
%                   audio = step(p, tone);
            end
            pause(0.001);
        end
       
        function [tone, tVec, env] = genTone(amp, freq, dur, riseDur, phase, Fs)
            % amp = amplitude, arb. (eg. Volts)
            % freq = frequency, Hz
            % dur = duration, S
            % riseDur = rise/fall time, in S
            % Fs = Sampling frequency, Hz
            
            % Calculate number of points required
            dt = 1/Fs;
            % Length in pts
            % npts (pts) = dur (S) * Fs (pts/S)
            nPts = round(dur * Fs);
            assert(nPts ~= 0, 'no points to generate tone with!');
            % Generate time vector
            tVec = 0:dt:dur-dt;
            
            % Generate tone
            tone = amp * sin(pi*2*freq.*tVec + phase);
            
            % Generate rise/fall
            nPtsRise = round(riseDur * Fs);
            tVecRise = -pi/2 : abs(-pi/2-0)/(nPtsRise-1) : 0;
            % Equiv to linspace(-pi/2, 0, nPtsRise)
            
            % Generate rise
            rise = cos(tVecRise);
            fall = rise(end:-1:1);
            
            % Add to envelope
            env = ones(1, nPts);
            env(1:nPtsRise) = rise;
            env(end-(nPtsRise-1):end) = fall;
            
            % Apply envelope to tone
            tone = env .* tone;
        end
    end

    methods (Access = private)
    
%         function player = GetNewPlayer(app)
%             player = new 
%         end
        
    end


    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            disp('Before TestTone');
            app.TestTone(app, app.decFreq);
            disp('After TestTone');
        end

        % Value changed function: FreqHzSlider
        function FreqHzSliderValueChanged(app, event)
            app.decFreq = app.FreqHzSlider.Value;
            
        end

        % Value changed function: btnPlay
        function btnPlayValueChanged(app, event)
            value = app.btnPlay.Value;
%             while app.btnPlay.Value
                app.TestTone(app, app.decFreq);
%             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SynthlabPROTOTYPELabel = uilabel(app.UIFigure);
            app.SynthlabPROTOTYPELabel.Text = 'Synthlab – PROTOTYPE';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.FreqHzSliderLabel = uilabel(app.UIFigure);
            app.FreqHzSliderLabel.Text = 'Freq (Hz)';
            app.FreqHzSlider = uislider(app.UIFigure);
            app.FreqHzSlider.Limits = [500 15000];
            app.FreqHzSlider.ValueChangedFcn = createCallbackFcn(app, @FreqHzSliderValueChanged, true);
            app.FreqHzSlider.Value = 2500;
            app.btnPlay = uibutton(app.UIFigure, 'state');
            app.btnPlay.ValueChangedFcn = createCallbackFcn(app, @btnPlayValueChanged, true);
            app.btnPlay.Text = 'PLAY2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiPrototype

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end